classdef SAR_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label                           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ConfigurationTab                matlab.ui.container.Tab
        PulsePerRevolutionEditFieldLabel  matlab.ui.control.Label
        PulsePerRevolutionEditField     matlab.ui.control.NumericEditField
        RevolutionPerMinuteEditFieldLabel  matlab.ui.control.Label
        RevolutionPerMinuteEditField    matlab.ui.control.NumericEditField
        DistancePerRevolutionmmEditFieldLabel  matlab.ui.control.Label
        DistancePerRevolutionmmEditField  matlab.ui.control.NumericEditField
        DisconnectfromActuatorButton    matlab.ui.control.Button
        ConnecttoActuatorButton         matlab.ui.control.Button
        SingleMovementPanel             matlab.ui.container.Panel
        DesiredHorizontalMovementmmEditFieldLabel  matlab.ui.control.Label
        DesiredHorizontalMovementmmEditField  matlab.ui.control.NumericEditField
        DesiredVerticalMovementmmEditFieldLabel  matlab.ui.control.Label
        DesiredVerticalMovementmmEditField  matlab.ui.control.NumericEditField
        SendSingleCommandButton         matlab.ui.control.Button
        TotalMovementLabel              matlab.ui.control.Label
        TotalMovementTextArea           matlab.ui.control.TextArea
        ResetButton                     matlab.ui.control.Button
        ContinuousMovementPanel         matlab.ui.container.Panel
        SendContinuousCommandButton     matlab.ui.control.Button
        MovementforEachStepmmEditFieldLabel  matlab.ui.control.Label
        MovementforEachStepmmEditField  matlab.ui.control.NumericEditField
        WaitTimeBetweenStepssEditFieldLabel  matlab.ui.control.Label
        WaitTimeBetweenStepsEditField   matlab.ui.control.NumericEditField
        NumberofStepsEditField_2Label   matlab.ui.control.Label
        NumberofStepsEditField          matlab.ui.control.NumericEditField
        MovementDirectionLabel          matlab.ui.control.Label
        DirectionSwitchContinous        matlab.ui.control.Switch
        ConnectionStatusLampLabel       matlab.ui.control.Label
        ActuatorConnectionStatusLamp    matlab.ui.control.Lamp
        ConnectToRadarButton            matlab.ui.control.Button
        ConnectionStatusLampLabel_3     matlab.ui.control.Label
        RadarConnectionStatusLamp       matlab.ui.control.Lamp
        TriggerFrameButton              matlab.ui.control.Button
        RadarScreenLabel                matlab.ui.control.Label
        RadarScreenTextArea             matlab.ui.control.TextArea
        RadarTypeSwitch_2Label          matlab.ui.control.Label
        RadarTypeSwitch                 matlab.ui.control.Switch
        SetRPMButton                    matlab.ui.control.Button
        SARScenarioTab                  matlab.ui.container.Tab
        SARDataCapturingPanel           matlab.ui.container.Panel
        StartCapturingSARCommandButton  matlab.ui.control.Button
        HorizontalMovementforEachStepmmEditFieldLabel  matlab.ui.control.Label
        HorizontalMovementforEachStepmmEditField  matlab.ui.control.NumericEditField
        WaitTimeBetweenStepssEditField_2Label  matlab.ui.control.Label
        WaitTimeBetweenStepsSAREditField  matlab.ui.control.NumericEditField
        NumberofStepsatHorizontalAxisEditFieldLabel  matlab.ui.control.Label
        NumberofStepsatHorizontalAxisEditField  matlab.ui.control.NumericEditField
        RadarTypeLabel                  matlab.ui.control.Label
        RadarTypeSwitchSAR              matlab.ui.control.Switch
        VerticalMovementforEachStepmmEditFieldLabel  matlab.ui.control.Label
        VerticalMovementforEachStepmmEditField  matlab.ui.control.NumericEditField
        NumberofStepsatVerticalAxisEditFieldLabel  matlab.ui.control.Label
        NumberofStepsatVerticalAxisEditField  matlab.ui.control.NumericEditField
        StopCapturingSARCommandButton   matlab.ui.control.Button
        ResetMovementSARCommandButton   matlab.ui.control.Button
        TotalMovementTextArea_2Label    matlab.ui.control.Label
        TotalMovementSARTextArea        matlab.ui.control.TextArea
        RadarSerialControlTab           matlab.ui.container.Tab
        ConnecttoRadarButton            matlab.ui.control.Button
        DisconnectfromRadarButton       matlab.ui.control.Button
        ConnectionStatusLampLabel_2     matlab.ui.control.Label
        RadarConnectionStatusLamp2      matlab.ui.control.Lamp
        SendRadarConfigurationButton    matlab.ui.control.Button
        StartTriggerButton              matlab.ui.control.Button
    end


    properties (Access = public)
        serialPortActuator;
        serialPortRadar;
        totalHorizontalMovement = 0;
        totalVerticalMovement = 0;
        textMovementString = ' ';
        textRadarStudioErrorString = ' ';
        isTIRadarConnected = false;
        isINRASRadarConnected = true;
        
        InrasCalData;
    end

    methods (Access = public)
        
        
    end


    methods (Access = private)

        % Button pushed function: ConnectToRadarButton
        function ConnectToRadarButtonPushed(app, event)
            radarType = app.RadarTypeSwitch.Value;
            radarTypeItems = app.RadarTypeSwitch.Items;
                    
            if strcmp(radarType,radarTypeItems(1))
                %% Do TI Work
                RSTD_DLL_Path = 'C:\ti\mmwave_dfp_00_09_01_06\rf_eval\radarstudio\Clients\RtttNetClientController\RtttNetClientAPI.dll';
                ErrStatus = Init_RSTD_Connection(RSTD_DLL_Path);
                if (ErrStatus ~= 30000)
                    app.RadarScreenTextArea.Value = 'Error inside Init_RSTD_Connection';
                    app.RadarConnectionStatusLamp.Color = 'red';
                    return;
                else
                    app.RadarScreenTextArea.Value = 'RSTD connection is successful';
                    app.RadarConnectionStatusLamp.Color = 'green';
                    app.isTIRadarConnected = true;
                end
            else
                %% Do INRAS Work
                evalin('base','[Brd,Cfg,CalData,RowData,nCall] = Initialize_INRAS_77GHz_TDM();');
                app.RadarScreenTextArea.Value = 'INRAS connection is successful';
                app.RadarConnectionStatusLamp.Color = 'green';
            end


        end

        % Button pushed function: ConnecttoActuatorButton
        function ConnecttoActuatorButtonPushed(app, event)
            serialPortList = seriallist;
            if (isempty(serialPortList))
                msgbox('No Available Serial Ports');
            else
            [selectedPort,~] = listdlg('PromptString','Select Serial Port:',...
                'SelectionMode','single',...
                'ListString',serialPortList);
            app.serialPortActuator = serial(serialPortList(selectedPort),'BaudRate',115200);
            fopen(app.serialPortActuator);
            app.ActuatorConnectionStatusLamp.Color = 'green';
            end
        end

        % Button pushed function: ConnecttoRadarButton
        function ConnecttoRadarButtonPushed(app, event)
            serialPortList = seriallist;
            if (isempty(serialPortList))
                msgbox('No Available Serial Ports');
            else
            [selectedPort,~] = listdlg('PromptString','Select Serial Port:',...
                'SelectionMode','single',...
                'ListString',serialPortList);
            app.serialPortRadar = serial(serialPortList(selectedPort),'BaudRate',115200);
            fopen(app.serialPortRadar);
            app.RadarConnectionStatusLamp2.Color = 'green';
            end
        end

        % Button pushed function: DisconnectfromActuatorButton
        function DisconnectfromActuatorButtonPushed(app, event)
            fclose(app.serialPortActuator);
            delete(app.serialPortActuator);
            app.ActuatorConnectionStatusLamp.Color = 'red';
        end

        % Button pushed function: DisconnectfromRadarButton
        function DisconnectfromRadarButtonPushed(app, event)
            fclose(app.serialPortRadar);
            delete(app.serialPortRadar);
            app.RadarConnectionStatusLamp2.Color = 'red';
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.totalHorizontalMovement = 0;
            app.totalVerticalMovement = 0;
            app.textMovementString = ['Horizontal:       ', num2str(app.totalHorizontalMovement), ' mm', newline, newline, ...;
                'Vertical:        ', num2str(app.totalVerticalMovement), ' mm'];
            app.TotalMovementTextArea.Value = app.textMovementString;
        end

        % Button pushed function: ResetMovementSARCommandButton
        function ResetMovementSARCommandButtonPushed(app, event)
            app.totalHorizontalMovement = 0;
            app.totalVerticalMovement = 0;
            app.textMovementString = ['Horizontal:       ', num2str(app.totalHorizontalMovement), ' mm', newline, newline, ...;
                'Vertical:        ', num2str(app.totalVerticalMovement), ' mm'];
            app.TotalMovementSARTextArea.Value = app.textMovementString;
        end

        % Button pushed function: SendContinuousCommandButton
        function SendContinuousCommandButtonPushed(app, event)
            pulsePerRevolution = app.PulsePerRevolutionEditField.Value;
            revolutionPerMinute = app.RevolutionPerMinuteEditField.Value;
            distancePerRevolution = app.DistancePerRevolutionmmEditField.Value;
            
            numberOfSteps = app.NumberofStepsEditField.Value;
            stepMovement = app.MovementforEachStepmmEditField.Value;
            movementDirection = app.DirectionSwitchContinous.Value;
            movementDirectionItems = app.DirectionSwitchContinous.Items;
            waitDuration = app.WaitTimeBetweenStepsEditField.Value;
            
            nPulse = round(stepMovement / distancePerRevolution * pulsePerRevolution);
            
            if strcmp(movementDirection,movementDirectionItems(1))
                command = 'V';
            else
                command = 'H';
            end
            
            for n = 1:numberOfSteps
                fprintf(app.serialPortActuator,[command num2str(nPulse)]);
                % disp([command num2str(nPulse)])
                pause(1/revolutionPerMinute*abs(nPulse)/pulsePerRevolution * 60);
                pause(waitDuration)
            end
            
        end

        % Button pushed function: SendRadarConfigurationButton
        function SendRadarConfigurationButtonPushed(app, event)
            clc;
            
            %% Default Parameters
            fprintf(app.serialPortRadar,'flushCfg');
            pause(0.1); fscanf(app.serialPortRadar); fscanf(app.serialPortRadar);
            fprintf(app.serialPortRadar,'dfeDataOutputMode 1');
            pause(0.1); fscanf(app.serialPortRadar); fscanf(app.serialPortRadar);
            fprintf(app.serialPortRadar,'channelCfg 2 1 0');
            pause(0.1); fscanf(app.serialPortRadar); fscanf(app.serialPortRadar);
            fprintf(app.serialPortRadar,'adcCfg 2 2');
            pause(0.1); fscanf(app.serialPortRadar); fscanf(app.serialPortRadar);
            fprintf(app.serialPortRadar,'adcbufCfg 0 0 0 1');
            pause(0.1); fscanf(app.serialPortRadar); fscanf(app.serialPortRadar);
            fprintf(app.serialPortRadar,'lowPower 0 0');
            pause(0.1); fscanf(app.serialPortRadar); fscanf(app.serialPortRadar);
            fprintf(app.serialPortRadar,'profileCfg 0 77 20 5 80 0 0 40 1 256 8000 0 0 30');
            pause(0.1); fscanf(app.serialPortRadar); fscanf(app.serialPortRadar);
            fprintf(app.serialPortRadar,'chirpCfg 0 0 0 0 0 0 0 1');
            pause(0.1); fscanf(app.serialPortRadar); fscanf(app.serialPortRadar);
            fprintf(app.serialPortRadar,'frameCfg 0 0 128 1024 20 1 0');
            pause(0.1); fscanf(app.serialPortRadar); fscanf(app.serialPortRadar);
            fprintf(app.serialPortRadar,'setHSI LVDS');
            pause(0.1); fscanf(app.serialPortRadar); fscanf(app.serialPortRadar);
            
            %% Programmed Parameters
            %             fprintf(app.serialPortRadar,'flushCfg');
            %             pause(0.1); fscanf(app.serialPortRadar); fscanf(app.serialPortRadar);
            %             fprintf(app.serialPortRadar,'dfeDataOutputMode 1');
            %             pause(0.1); fscanf(app.serialPortRadar); fscanf(app.serialPortRadar);
            %             fprintf(app.serialPortRadar,'channelCfg 15 5 0');
            %             pause(0.1); fscanf(app.serialPortRadar); fscanf(app.serialPortRadar);
            %             fprintf(app.serialPortRadar,'adcCfg 2 2');
            %             pause(0.1); fscanf(app.serialPortRadar); fscanf(app.serialPortRadar);
            %             fprintf(app.serialPortRadar,'adcbufCfg 0 0 0 1');
            %             pause(0.1); fscanf(app.serialPortRadar); fscanf(app.serialPortRadar);
            %             fprintf(app.serialPortRadar,'lowPower 0 0');
            %             pause(0.1); fscanf(app.serialPortRadar); fscanf(app.serialPortRadar);
            %             fprintf(app.serialPortRadar,'profileCfg 0 77 7 3 60 0 0 60 0 256 10000 0 0 30');
            %             pause(0.1); fscanf(app.serialPortRadar); fscanf(app.serialPortRadar);
            %             fprintf(app.serialPortRadar,'chirpCfg 0 0 0 0 0 0 0 5');
            %             pause(0.1); fscanf(app.serialPortRadar); fscanf(app.serialPortRadar);
            %             fprintf(app.serialPortRadar,'frameCfg 0 0 128 1 10 1 0');
            %             pause(0.1); fscanf(app.serialPortRadar); fscanf(app.serialPortRadar);
            %             fprintf(app.serialPortRadar,'setHSI LVDS');
            %             pause(0.1); fscanf(app.serialPortRadar); fscanf(app.serialPortRadar);
        end

        % Button pushed function: SendSingleCommandButton
        function SendSingleCommandButtonPushed(app, event)
            pulsePerRevolution = app.PulsePerRevolutionEditField.Value;
            % revolutionPerMinute = app.RevolutionPerMinuteEditField.Value;
            distancePerRevolution = app.DistancePerRevolutionmmEditField.Value;
            
            desiredMovementH = app.DesiredHorizontalMovementmmEditField.Value;
            desiredMovementV = app.DesiredVerticalMovementmmEditField.Value;
            
            nPulseH = round(desiredMovementH / distancePerRevolution * pulsePerRevolution);
            nPulseV = round(desiredMovementV / distancePerRevolution * pulsePerRevolution);
            
            fprintf(app.serialPortActuator,['H' num2str(nPulseH)]);
            % pause(1/revolutionPerMinute*abs(nPulseH)/pulsePerRevolution * 60);
            fprintf(app.serialPortActuator,['V' num2str(nPulseV)]);
            % pause(1/revolutionPerMinute*abs(nPulseV)/pulsePerRevolution * 60);
            
            app.totalHorizontalMovement = app.totalHorizontalMovement + desiredMovementH;
            app.totalVerticalMovement = app.totalVerticalMovement + desiredMovementV;
            app.textMovementString = ['Horizontal:    ', num2str(app.totalHorizontalMovement), ' mm', newline, newline, ...;
                'Vertical:        ', num2str(app.totalVerticalMovement), ' mm'];
            app.TotalMovementTextArea.Value = app.textMovementString;
            
        end

        % Button pushed function: StartCapturingSARCommandButton
        function StartCapturingSARCommandButtonPushed(app, event)
            pulsePerRevolution = app.PulsePerRevolutionEditField.Value;
            revolutionPerMinute = app.RevolutionPerMinuteEditField.Value;
            distancePerRevolution = app.DistancePerRevolutionmmEditField.Value;
            
            numberOfHorizontalSteps = app.NumberofStepsatHorizontalAxisEditField.Value;
            stepHorizontalMovement = app.HorizontalMovementforEachStepmmEditField.Value;
            numberOfVerticalSteps = app.NumberofStepsatVerticalAxisEditField.Value;
            stepVerticalMovement = app.VerticalMovementforEachStepmmEditField.Value;
            
            waitDuration = app.WaitTimeBetweenStepsSAREditField.Value;
            
            nPulseHorizontal = round(stepHorizontalMovement / distancePerRevolution * pulsePerRevolution);
            nPulseVertical = round(stepVerticalMovement / distancePerRevolution * pulsePerRevolution);
            
            radarType = app.RadarTypeSwitchSAR.Value;
            radarTypeItems = app.RadarTypeSwitchSAR.Items;
            
            for nV = 1:numberOfVerticalSteps
                
                if (strcmp(radarType,radarTypeItems(1)) && app.isTIRadarConnected)
                    %% Do TI Work
                    Lua_String = sprintf('ar1.StartFrame()');
                    ErrStatus = RtttNetClientAPI.RtttNetClient.SendCommand(Lua_String);
                    if (ErrStatus ~=30000)
                        app.RadarScreenTextArea.Value = 'Trigger Frame failed for device';
                        return;
                    else
                        app.RadarScreenTextArea.Value = 'Trigger Frame is successful';
                    end
                else
                    %% Do INRAS Work
                    evalin('base','[RowData,nCall] = Capture_INRAS_77GHz_TDM(Brd,Cfg,CalData,RowData,nCall);');
                    app.RadarScreenTextArea.Value = 'Trigger Frame is successful';
                end
                pause(waitDuration/2)
                
                for nH = 1:numberOfHorizontalSteps-1
                    nPulse = nPulseHorizontal;
                    command = ['H' num2str(nPulse)];
                    fprintf(app.serialPortActuator,command);
                    pause(1/revolutionPerMinute*abs(nPulse)/pulsePerRevolution * 60);
                    app.totalHorizontalMovement = app.totalHorizontalMovement + stepHorizontalMovement;
                    app.textMovementString = ['Horizontal:    ', num2str(app.totalHorizontalMovement), ' mm', newline, newline, ...;
                        'Vertical:        ', num2str(app.totalVerticalMovement), ' mm'];
                    app.TotalMovementSARTextArea.Value = app.textMovementString;
                    % disp(command)
                    pause(waitDuration/2)
                    
                    if (strcmp(radarType,radarTypeItems(1)) && app.isTIRadarConnected)
                        %% Do TI Work
                        Lua_String = sprintf('ar1.StartFrame()');
                        ErrStatus = RtttNetClientAPI.RtttNetClient.SendCommand(Lua_String);
                        if (ErrStatus ~=30000)
                            app.RadarScreenTextArea.Value = 'Trigger Frame failed for device';
                            return;
                        else
                            app.RadarScreenTextArea.Value = 'Trigger Frame is successful';
                        end
                    else
                        %% Do INRAS Work
                        evalin('base','[RowData,nCall] = Capture_INRAS_77GHz_TDM(Brd,Cfg,CalData,RowData,nCall);');
                        app.RadarScreenTextArea.Value = 'Trigger Frame is successful';
                    end
                    pause(waitDuration/2)                    
                    
                end
                
                nPulse = -1 * (numberOfHorizontalSteps-1) * nPulseHorizontal;
                command = ['H' num2str(nPulse)];
                fprintf(app.serialPortActuator,command);
                pause(1/revolutionPerMinute*abs(nPulse)/pulsePerRevolution * 60);
                app.totalHorizontalMovement = app.totalHorizontalMovement - (numberOfHorizontalSteps-1) * stepHorizontalMovement;
                app.textMovementString = ['Horizontal:    ', num2str(app.totalHorizontalMovement), ' mm', newline, newline, ...;
                    'Vertical:        ', num2str(app.totalVerticalMovement), ' mm'];
                app.TotalMovementSARTextArea.Value = app.textMovementString;
                % disp(command)
                pause(waitDuration/2)
                
                if nV ~= numberOfVerticalSteps
                    nPulse = nPulseVertical;
                    command = ['V' num2str(nPulse)];
                    fprintf(app.serialPortActuator,command);
                    pause(1/revolutionPerMinute*abs(nPulse)/pulsePerRevolution * 60);
                    app.totalVerticalMovement = app.totalVerticalMovement + stepVerticalMovement;
                    app.textMovementString = ['Horizontal:    ', num2str(app.totalHorizontalMovement), ' mm', newline, newline, ...;
                        'Vertical:        ', num2str(app.totalVerticalMovement), ' mm'];
                    app.TotalMovementSARTextArea.Value = app.textMovementString;
                    % disp(command)
                    pause(waitDuration/2)
                    
                end
                
            end
        end

        % Button pushed function: StartTriggerButton
        function StartTriggerButtonPushed(app, event)
            fprintf(app.serialPortRadar,'sensorStart');
        end

        % Button pushed function: StopCapturingSARCommandButton
        function StopCapturingSARCommandButtonPushed(app, event)
            % Do some stop work
        end

        % Button pushed function: TriggerFrameButton
        function TriggerFrameButtonPushed(app, event)
            radarType = app.RadarTypeSwitch.Value;
            radarTypeItems = app.RadarTypeSwitch.Items;
            
            if (strcmp(radarType,radarTypeItems(1)) && app.isTIRadarConnected) 
                %% Do TI Work
                Lua_String = sprintf('ar1.StartFrame()');
                ErrStatus = RtttNetClientAPI.RtttNetClient.SendCommand(Lua_String);
                if (ErrStatus ~=30000)
                    app.RadarScreenTextArea.Value = 'Trigger Frame failed for device';
                    return;
                else
                    app.RadarScreenTextArea.Value = 'Trigger Frame is successful';
                end
            else
                %% Do INRAS Work
                evalin('base','[RowData,nCall] = Capture_INRAS_77GHz_TDM(Brd,Cfg,CalData,RowData,nCall);');
                app.RadarScreenTextArea.Value = 'Trigger Frame is successful';
            end              
        end

        % Button pushed function: SetRPMButton
        function SetRPMButtonPushed(app, event)
            revolutionPerMinute = app.RevolutionPerMinuteEditField.Value;
            fprintf(app.serialPortActuator,['S' num2str(revolutionPerMinute)]);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Created by Muhammet Emin YANIK'; 'Advisor: Prof. Murat TORLAK'; 'The University of Texas at Dallas'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.PulsePerRevolutionEditFieldLabel = uilabel(app.ConfigurationTab);
            app.PulsePerRevolutionEditFieldLabel.Text = 'Pulse Per Revolution';
            app.PulsePerRevolutionEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.PulsePerRevolutionEditField.Editable = 'off';
            app.PulsePerRevolutionEditField.Value = 3200;
            app.RevolutionPerMinuteEditFieldLabel = uilabel(app.ConfigurationTab);
            app.RevolutionPerMinuteEditFieldLabel.Text = 'Revolution Per Minute';
            app.RevolutionPerMinuteEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.RevolutionPerMinuteEditField.Value = 60;
            app.DistancePerRevolutionmmEditFieldLabel = uilabel(app.ConfigurationTab);
            app.DistancePerRevolutionmmEditFieldLabel.Text = 'Distance Per Revolution (mm)';
            app.DistancePerRevolutionmmEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.DistancePerRevolutionmmEditField.Editable = 'off';
            app.DistancePerRevolutionmmEditField.Value = 5;
            app.DisconnectfromActuatorButton = uibutton(app.ConfigurationTab, 'push');
            app.DisconnectfromActuatorButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectfromActuatorButtonPushed, true);
            app.DisconnectfromActuatorButton.Text = 'Disconnect';
            app.ConnecttoActuatorButton = uibutton(app.ConfigurationTab, 'push');
            app.ConnecttoActuatorButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoActuatorButtonPushed, true);
            app.ConnecttoActuatorButton.Text = 'Connect to Device';
            app.SingleMovementPanel = uipanel(app.ConfigurationTab);
            app.SingleMovementPanel.Title = 'Single Movement';
            app.DesiredHorizontalMovementmmEditFieldLabel = uilabel(app.SingleMovementPanel);
            app.DesiredHorizontalMovementmmEditFieldLabel.Text = 'Desired Horizontal Movement (mm)';
            app.DesiredHorizontalMovementmmEditField = uieditfield(app.SingleMovementPanel, 'numeric');
            app.DesiredVerticalMovementmmEditFieldLabel = uilabel(app.SingleMovementPanel);
            app.DesiredVerticalMovementmmEditFieldLabel.Text = 'Desired Vertical Movement (mm)';
            app.DesiredVerticalMovementmmEditField = uieditfield(app.SingleMovementPanel, 'numeric');
            app.SendSingleCommandButton = uibutton(app.SingleMovementPanel, 'push');
            app.SendSingleCommandButton.ButtonPushedFcn = createCallbackFcn(app, @SendSingleCommandButtonPushed, true);
            app.SendSingleCommandButton.Text = 'Send Command';
            app.TotalMovementLabel = uilabel(app.SingleMovementPanel);
            app.TotalMovementLabel.Text = 'Total Movement';
            app.TotalMovementTextArea = uitextarea(app.SingleMovementPanel);
            app.ResetButton = uibutton(app.SingleMovementPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ContinuousMovementPanel = uipanel(app.ConfigurationTab);
            app.ContinuousMovementPanel.Title = 'Continuous Movement';
            app.SendContinuousCommandButton = uibutton(app.ContinuousMovementPanel, 'push');
            app.SendContinuousCommandButton.ButtonPushedFcn = createCallbackFcn(app, @SendContinuousCommandButtonPushed, true);
            app.SendContinuousCommandButton.Text = 'Send Command';
            app.MovementforEachStepmmEditFieldLabel = uilabel(app.ContinuousMovementPanel);
            app.MovementforEachStepmmEditFieldLabel.Text = 'Movement for Each Step (mm)';
            app.MovementforEachStepmmEditField = uieditfield(app.ContinuousMovementPanel, 'numeric');
            app.WaitTimeBetweenStepssEditFieldLabel = uilabel(app.ContinuousMovementPanel);
            app.WaitTimeBetweenStepssEditFieldLabel.Text = 'Wait Time Between Steps (s)';
            app.WaitTimeBetweenStepsEditField = uieditfield(app.ContinuousMovementPanel, 'numeric');
            app.NumberofStepsEditField_2Label = uilabel(app.ContinuousMovementPanel);
            app.NumberofStepsEditField_2Label.Text = 'Number of Steps';
            app.NumberofStepsEditField = uieditfield(app.ContinuousMovementPanel, 'numeric');
            app.MovementDirectionLabel = uilabel(app.ContinuousMovementPanel);
            app.MovementDirectionLabel.Text = 'Movement Direction';
            app.DirectionSwitchContinous = uiswitch(app.ContinuousMovementPanel, 'slider');
            app.DirectionSwitchContinous.Items = {'Vertical', 'Horizontal'};
            app.DirectionSwitchContinous.Value = 'Vertical';
            app.ConnectionStatusLampLabel = uilabel(app.ConfigurationTab);
            app.ConnectionStatusLampLabel.Text = 'Connection Status';
            app.ActuatorConnectionStatusLamp = uilamp(app.ConfigurationTab);
            app.ConnectToRadarButton = uibutton(app.ConfigurationTab, 'push');
            app.ConnectToRadarButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectToRadarButtonPushed, true);
            app.ConnectToRadarButton.Text = 'Connect To Radar';
            app.ConnectionStatusLampLabel_3 = uilabel(app.ConfigurationTab);
            app.ConnectionStatusLampLabel_3.Text = 'Connection Status';
            app.RadarConnectionStatusLamp = uilamp(app.ConfigurationTab);
            app.TriggerFrameButton = uibutton(app.ConfigurationTab, 'push');
            app.TriggerFrameButton.ButtonPushedFcn = createCallbackFcn(app, @TriggerFrameButtonPushed, true);
            app.TriggerFrameButton.Text = 'Trigger Frame';
            app.RadarScreenLabel = uilabel(app.ConfigurationTab);
            app.RadarScreenLabel.Text = 'Radar Screen';
            app.RadarScreenTextArea = uitextarea(app.ConfigurationTab);
            app.RadarTypeSwitch_2Label = uilabel(app.ConfigurationTab);
            app.RadarTypeSwitch_2Label.Text = 'Radar Type';
            app.RadarTypeSwitch = uiswitch(app.ConfigurationTab, 'slider');
            app.RadarTypeSwitch.Items = {'TI', 'INRAS'};
            app.RadarTypeSwitch.Value = 'TI';
            app.SetRPMButton = uibutton(app.ConfigurationTab, 'push');
            app.SetRPMButton.ButtonPushedFcn = createCallbackFcn(app, @SetRPMButtonPushed, true);
            app.SetRPMButton.Text = 'Set RPM';
            app.SARScenarioTab = uitab(app.TabGroup);
            app.SARScenarioTab.Title = 'SAR Scenario';
            app.SARDataCapturingPanel = uipanel(app.SARScenarioTab);
            app.SARDataCapturingPanel.Title = 'SAR Data Capturing';
            app.StartCapturingSARCommandButton = uibutton(app.SARDataCapturingPanel, 'push');
            app.StartCapturingSARCommandButton.ButtonPushedFcn = createCallbackFcn(app, @StartCapturingSARCommandButtonPushed, true);
            app.StartCapturingSARCommandButton.Text = 'Start Capturing';
            app.HorizontalMovementforEachStepmmEditFieldLabel = uilabel(app.SARDataCapturingPanel);
            app.HorizontalMovementforEachStepmmEditFieldLabel.Text = 'Horizontal Movement for Each Step (mm)';
            app.HorizontalMovementforEachStepmmEditField = uieditfield(app.SARDataCapturingPanel, 'numeric');
            app.WaitTimeBetweenStepssEditField_2Label = uilabel(app.SARDataCapturingPanel);
            app.WaitTimeBetweenStepssEditField_2Label.Text = 'Wait Time Between Steps (s)';
            app.WaitTimeBetweenStepsSAREditField = uieditfield(app.SARDataCapturingPanel, 'numeric');
            app.WaitTimeBetweenStepsSAREditField.Value = 1;
            app.NumberofStepsatHorizontalAxisEditFieldLabel = uilabel(app.SARDataCapturingPanel);
            app.NumberofStepsatHorizontalAxisEditFieldLabel.Text = 'Number of Steps at Horizontal Axis';
            app.NumberofStepsatHorizontalAxisEditField = uieditfield(app.SARDataCapturingPanel, 'numeric');
            app.RadarTypeLabel = uilabel(app.SARDataCapturingPanel);
            app.RadarTypeLabel.Text = 'Radar Type';
            app.RadarTypeSwitchSAR = uiswitch(app.SARDataCapturingPanel, 'slider');
            app.RadarTypeSwitchSAR.Items = {'TI', 'INRAS'};
            app.RadarTypeSwitchSAR.Value = 'TI';
            app.VerticalMovementforEachStepmmEditFieldLabel = uilabel(app.SARDataCapturingPanel);
            app.VerticalMovementforEachStepmmEditFieldLabel.Text = 'Vertical Movement for Each Step (mm)';
            app.VerticalMovementforEachStepmmEditField = uieditfield(app.SARDataCapturingPanel, 'numeric');
            app.NumberofStepsatVerticalAxisEditFieldLabel = uilabel(app.SARDataCapturingPanel);
            app.NumberofStepsatVerticalAxisEditFieldLabel.Text = 'Number of Steps at Vertical Axis';
            app.NumberofStepsatVerticalAxisEditField = uieditfield(app.SARDataCapturingPanel, 'numeric');
            app.StopCapturingSARCommandButton = uibutton(app.SARDataCapturingPanel, 'push');
            app.StopCapturingSARCommandButton.ButtonPushedFcn = createCallbackFcn(app, @StopCapturingSARCommandButtonPushed, true);
            app.StopCapturingSARCommandButton.Text = 'Stop Capturing';
            app.ResetMovementSARCommandButton = uibutton(app.SARDataCapturingPanel, 'push');
            app.ResetMovementSARCommandButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMovementSARCommandButtonPushed, true);
            app.ResetMovementSARCommandButton.Text = 'Reset';
            app.TotalMovementTextArea_2Label = uilabel(app.SARDataCapturingPanel);
            app.TotalMovementTextArea_2Label.Text = 'Total Movement';
            app.TotalMovementSARTextArea = uitextarea(app.SARDataCapturingPanel);
            app.RadarSerialControlTab = uitab(app.TabGroup);
            app.RadarSerialControlTab.Title = 'Radar Serial Control';
            app.ConnecttoRadarButton = uibutton(app.RadarSerialControlTab, 'push');
            app.ConnecttoRadarButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoRadarButtonPushed, true);
            app.ConnecttoRadarButton.Text = 'Connect to Device';
            app.DisconnectfromRadarButton = uibutton(app.RadarSerialControlTab, 'push');
            app.DisconnectfromRadarButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectfromRadarButtonPushed, true);
            app.DisconnectfromRadarButton.Text = 'Disconnect';
            app.ConnectionStatusLampLabel_2 = uilabel(app.RadarSerialControlTab);
            app.ConnectionStatusLampLabel_2.Text = 'Connection Status';
            app.RadarConnectionStatusLamp2 = uilamp(app.RadarSerialControlTab);
            app.SendRadarConfigurationButton = uibutton(app.RadarSerialControlTab, 'push');
            app.SendRadarConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SendRadarConfigurationButtonPushed, true);
            app.SendRadarConfigurationButton.Text = 'Send Radar Configuration';
            app.StartTriggerButton = uibutton(app.RadarSerialControlTab, 'push');
            app.StartTriggerButton.ButtonPushedFcn = createCallbackFcn(app, @StartTriggerButtonPushed, true);
            app.StartTriggerButton.Text = 'Start Trigger';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SAR_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end